classdef NumericalAssignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnEducationalToolForNumericalAnalysisLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        WELCOMETab                      matlab.ui.container.Tab
        LETSSTARTLabel                  matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        BISECTIONMETHODTab              matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        BisectionDataTextAreaLabel      matlab.ui.control.Label
        BisectionDataTextArea           matlab.ui.control.TextArea
        BisectionIterationEditField     matlab.ui.control.NumericEditField
        BisectionIterationEditFieldLabel  matlab.ui.control.Label
        rootbisectionEditField          matlab.ui.control.NumericEditField
        rootbisectionEditFieldLabel     matlab.ui.control.Label
        BisectionMaxiterationEditField  matlab.ui.control.NumericEditField
        BisectionMaxiterationEditFieldLabel  matlab.ui.control.Label
        BisectionToleranceEditField     matlab.ui.control.NumericEditField
        BisectionToleranceEditFieldLabel  matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        FunctiongivenEditField          matlab.ui.control.EditField
        FunctiongivenEditFieldLabel     matlab.ui.control.Label
        maximumiterationcanbedoneLabel  matlab.ui.control.Label
        leveloferrorthatisacceptableLabel_2  matlab.ui.control.Label
        eg00001Label_2                  matlab.ui.control.Label
        InserttheintervalabLabel        matlab.ui.control.Label
        egx22x1Label_2                  matlab.ui.control.Label
        ANSWERLabel                     matlab.ui.control.Label
        ComputeRootButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        SECANTMETHODTab                 matlab.ui.container.Tab
        Image6                          matlab.ui.control.Image
        x0andx1cantbe0Label             matlab.ui.control.Label
        ANSWERLabel_2                   matlab.ui.control.Label
        MaxiterationEditField           matlab.ui.control.NumericEditField
        MaxiterationEditFieldLabel      matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        x1EditField                     matlab.ui.control.NumericEditField
        x1EditFieldLabel                matlab.ui.control.Label
        x0EditField                     matlab.ui.control.NumericEditField
        x0EditFieldLabel                matlab.ui.control.Label
        FunctionEditField               matlab.ui.control.EditField
        FunctionEditFieldLabel          matlab.ui.control.Label
        maximumiterationLabel           matlab.ui.control.Label
        eg00001Label                    matlab.ui.control.Label
        leveloferrorthatisacceptableLabel  matlab.ui.control.Label
        InserttheinitialguessLabel      matlab.ui.control.Label
        egx22x1Label                    matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        DataTextArea                    matlab.ui.control.TextArea
        DataTextAreaLabel               matlab.ui.control.Label
        IterationEditField              matlab.ui.control.NumericEditField
        IterationEditFieldLabel         matlab.ui.control.Label
        RootEditField                   matlab.ui.control.NumericEditField
        RootEditFieldLabel              matlab.ui.control.Label
        UIAxesSecant                    matlab.ui.control.UIAxes
        SPLINEINTERPOLATIONTab          matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        SplineInterpolationDataTextArea  matlab.ui.control.TextArea
        SplineInterpolationDataLabel    matlab.ui.control.Label
        XInterpEditField                matlab.ui.control.EditField
        XInterpEditFieldLabel           matlab.ui.control.Label
        YValuesEditField                matlab.ui.control.EditField
        YValuesEditFieldLabel           matlab.ui.control.Label
        XValuesEditField                matlab.ui.control.EditField
        XValuesEditFieldLabel           matlab.ui.control.Label
        xmustbeinbetweenXValuesrangeLabel  matlab.ui.control.Label
        InserttheXandYvaluesLabel       matlab.ui.control.Label
        FindyatxLabel                   matlab.ui.control.Label
        eg123or123Label_2               matlab.ui.control.Label
        eg123or123Label                 matlab.ui.control.Label
        InterpolateButton               matlab.ui.control.Button
        UIAxesSpline                    matlab.ui.control.UIAxes
        ERRORTab                        matlab.ui.container.Tab
        RelativeErrorEditField          matlab.ui.control.EditField
        RelativeErrorEditFieldLabel     matlab.ui.control.Label
        AbsoluteErrorEditField          matlab.ui.control.EditField
        AbsoluteErrorEditFieldLabel     matlab.ui.control.Label
        ANSWERLabel_3                   matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ApproximateValueEditField       matlab.ui.control.NumericEditField
        ApproximateValueEditFieldLabel  matlab.ui.control.Label
        ExactValueEditField             matlab.ui.control.NumericEditField
        ExactValueEditFieldLabel        matlab.ui.control.Label
        Image3_2                        matlab.ui.control.Image
        EULERTab                        matlab.ui.container.Tab
        Examplety1Label                 matlab.ui.control.Label
        NotesUsedifferentialeqnLabel    matlab.ui.control.Label
        ApproximateEditField            matlab.ui.control.NumericEditField
        ApproximateEditFieldLabel       matlab.ui.control.Label
        PressButton                     matlab.ui.control.Button
        nEditField_2                    matlab.ui.control.NumericEditField
        nEditField_2Label               matlab.ui.control.Label
        NumberofstepsLabel              matlab.ui.control.Label
        hEditField                      matlab.ui.control.NumericEditField
        hEditFieldLabel                 matlab.ui.control.Label
        StepsizeLabel                   matlab.ui.control.Label
        y0EditField                     matlab.ui.control.NumericEditField
        y0EditFieldLabel                matlab.ui.control.Label
        InitialvalueLabel               matlab.ui.control.Label
        t0EditField                     matlab.ui.control.NumericEditField
        t0EditFieldLabel                matlab.ui.control.Label
        InitialtimeLabel                matlab.ui.control.Label
        FunctionEditField_3             matlab.ui.control.EditField
        FunctionEditField_3Label        matlab.ui.control.Label
        Image3_4                        matlab.ui.control.Image
        TRAPEZOIDALRULETab              matlab.ui.container.Tab
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsTextAreaLabel            matlab.ui.control.Label
        nEditField_3                    matlab.ui.control.NumericEditField
        nEditField_3Label               matlab.ui.control.Label
        UpperlimitbEditField            matlab.ui.control.NumericEditField
        UpperlimitbEditFieldLabel       matlab.ui.control.Label
        LowerlimitaEditField            matlab.ui.control.NumericEditField
        LowerlimitaEditFieldLabel       matlab.ui.control.Label
        fxEditField                     matlab.ui.control.EditField
        fxEditFieldLabel                matlab.ui.control.Label
        ThestartoftheintervalLabel      matlab.ui.control.Label
        TheendoftheintervalLabel        matlab.ui.control.Label
        NumberofsubintervalsBasicn1Compositen1Label  matlab.ui.control.Label
        expx23x3Label                   matlab.ui.control.Label
        ComputeButtonTrapezoidal        matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        SIMPSONRULETab                  matlab.ui.container.Tab
        UpperlimitbEditField_2          matlab.ui.control.NumericEditField
        UpperlimitbEditField_2Label     matlab.ui.control.Label
        LowerlimitaEditField_2          matlab.ui.control.NumericEditField
        LowerlimitaEditField_2Label     matlab.ui.control.Label
        ResultsTextArea_2               matlab.ui.control.TextArea
        ResultsTextArea_2Label          matlab.ui.control.Label
        nEditField_4                    matlab.ui.control.NumericEditField
        nEditField_4Label               matlab.ui.control.Label
        fxEditField_2                   matlab.ui.control.EditField
        fxEditField_2Label              matlab.ui.control.Label
        NumberofsubintervalsBasicn2Compositen2nLabel  matlab.ui.control.Label
        ThestartoftheintervalLabel_2    matlab.ui.control.Label
        TheendoftheintervalLabel_2      matlab.ui.control.Label
        expx23x3Label_2                 matlab.ui.control.Label
        ComputeButtonSimpson            matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        MIDPOINTRULETab                 matlab.ui.container.Tab
        UpperlimitbEditField_3          matlab.ui.control.NumericEditField
        UpperlimitbEditField_3Label     matlab.ui.control.Label
        LowerlimitaEditField_3          matlab.ui.control.NumericEditField
        LowerlimitaEditField_3Label     matlab.ui.control.Label
        ResultsTextArea_3               matlab.ui.control.TextArea
        ResultsTextArea_3Label          matlab.ui.control.Label
        nEditField_5                    matlab.ui.control.NumericEditField
        nEditField_5Label               matlab.ui.control.Label
        fxEditField_3                   matlab.ui.control.EditField
        fxEditField_3Label              matlab.ui.control.Label
        NumberofsubintervalsBasicn1Compositen1Label_2  matlab.ui.control.Label
        ThestartoftheintervalLabel_3    matlab.ui.control.Label
        TheendoftheintervalLabel_3      matlab.ui.control.Label
        exp4x23x3Label                  matlab.ui.control.Label
        ComputeButtonMidpoint           matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        NEWTONRAPHSONMETHODTab          matlab.ui.container.Tab
        AnswerEditField                 matlab.ui.control.EditField
        AnswerEditFieldLabel            matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        FunctionEditField_4             matlab.ui.control.EditField
        FunctionfxLabel                 matlab.ui.control.Label
        ErrortoleranceEditField         matlab.ui.control.NumericEditField
        ErrortoleranceEditFieldLabel    matlab.ui.control.Label
        InitialguessEditField           matlab.ui.control.NumericEditField
        InitialguessaLabel              matlab.ui.control.Label
        MaximumiterationcanbedoneLabel  matlab.ui.control.Label
        LeveloferrorthatisacceptableLabel  matlab.ui.control.Label
        Expx33x1Label                   matlab.ui.control.Label
        ResultTable                     matlab.ui.control.Table
        ComputeRoot                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
try 
    app.DataTextArea.Value = '';
    app.RootEditField.Value = 0; % Set to 0 if 'AllowEmpty' is 'off' for numeric fields
    app.IterationEditField.Value = 0; % Set to 0 if 'AllowEmpty' is 'off' for numeric fields
    cla(app.UIAxesSecant);

    % Read inputs 
    f_str = app.FunctionEditField.Value; 
    f = str2func(['@(x)', f_str]); 
     
    x0 = app.x0EditField.Value;  % Initial guess 1 
    x1 = app.x1EditField.Value;  % Initial guess 2 
    tol = app.ToleranceEditField.Value; 
    maxIter = app.MaxiterationEditField.Value; 
     
    iter = 0; 
    data = []; 
     
    while iter < maxIter 
        fx0 = f(x0);  % f(x_{n-1}) 
        fx1 = f(x1);  % f(x_n) 
        
        if fx1 == fx0 
            error('Division by zero encountered in Secant formula. This occurs if f(x_n) equals f(x_{n-1}), making the secant line horizontal.'); 
        end 
        
        x2 = x1 - fx1 * (x1 - x0) / (fx1 - fx0);  % x_{n+1} 
        iter = iter + 1; 
        % Store iteration data 
        data(end+1,:) = [iter, x0, x1, fx0, fx1, x2, abs(x2 - x1)]; %#ok<AGROW>
        
        % Check convergence 
        if abs(x2 - x1) < tol 
            break; 
        end 
        
        % Update for next iteration 
        x0 = x1; 
        x1 = x2; 
     
    end 
     
    % Display root and iterations 
    app.RootEditField.Value = x2; 
    app.IterationEditField.Value = iter; 
     
    % Format and display iteration data in TextArea 
    header = sprintf('%5s\t%20s\t%20s\t%20s\t%15s\t%15s\t%15s', ... 
    'Iter', 'x(n-1)', 'x(n)', 'f(x(n-1))', 'f(x(n))', 'x(n+1)', '|x(n+1)-x(n)|'); 
    rows = cell(size(data,1),1); 
     
    for i = 1:size(data,1) 
        rows{i} = sprintf('%5d\t%15.7f\t%15.7f\t%15.7f\t%15.7f\t%15.7f\t%15.7f', ... 
        data(i,1), data(i,2), data(i,3), data(i,4), data(i,5), data(i,6), data(i,7)); 
    end 
    % Assign to TextArea 
    app.DataTextArea.Value = [{header}; rows]; 
     
    % Plot function and root 
    % Define the plotting range based on initial guesses and the found root, extended slightly.
    x_vals = linspace(min([x0, x1, x2]) - 1, max([x0, x1, x2]) + 1, 400); 
    y_vals = arrayfun(f, x_vals); 
     
    cla(app.UIAxesSecant); % Clear the axes before plotting
    plot(app.UIAxesSecant, x_vals, y_vals, 'b-', 'LineWidth', 1.5, 'DisplayName', 'f(x)'); 
    hold(app.UIAxesSecant, 'on'); 
    
    % Plot the found root
    plot(app.UIAxesSecant, x2, f(x2), 'ro', 'MarkerSize', 8, 'DisplayName', 'Root'); 
    
    % Add a line at y=0 to clearly show where the root is
    plot(app.UIAxesSecant, x_vals, zeros(size(x_vals)), 'k:', 'DisplayName', 'Y=0');

    % Set title and labels
    title(app.UIAxesSecant, 'Function Plot with Root (Secant Method)'); 
    xlabel(app.UIAxesSecant, 'x'); 
    ylabel(app.UIAxesSecant, 'f(x)'); 
    grid(app.UIAxesSecant, 'on'); 
    legend(app.UIAxesSecant, 'Location', 'best'); % Add legend
    hold(app.UIAxesSecant, 'off'); 
     
catch ME 
    % Handle errors and display them in the DataTextArea
    app.DataTextArea.Value = {['Error: ', ME.message]}; 
    % Clear other fields/axes in case of error 
    app.RootEditField.Value = 0; % Set to 0 if 'AllowEmpty' is 'off'
    app.IterationEditField.Value = 0; % Set to 0 if 'AllowEmpty' is 'off'
    cla(app.UIAxesSecant); 
end
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
try
    % 1. Read inputs from the edit fields
    x_str = app.XValuesEditField.Value;
    y_str = app.YValuesEditField.Value;
    
    x_interp_str = app.XInterpEditField.Value; 
    x_interp = str2double(x_interp_str); % Convert string to double for x_interp

    % Convert string data for X and Y points to numerical arrays
    x_data = str2num(x_str); %#ok<ST2NM>
    y_data = str2num(y_str); %#ok<ST2NM>

    % Input validation: Check if conversion was successful and inputs are valid
    if isempty(x_data) || isempty(y_data) || ~isvector(x_data) || ~isvector(y_data)
        error('Please enter valid numerical data (space or comma separated) for X Values and Y Values.');
    end
    
    if isempty(x_interp) || ~isscalar(x_interp) || isnan(x_interp)
        error('Please enter a single valid numerical value for X to interpolate.');
    end

    if length(x_data) ~= length(y_data)
        app.SplineInterpolationDataTextArea.Value = {'Error: X and Y data must be of the same length.'};
        cla(app.UIAxesSpline); % Clear graph on error
        return; 
    end

    % Spline interpolation requires at least 2 data points.
    % (interp1('spline') will use linear for 2 points, quadratic for 3 points)
    if length(x_data) < 2
        app.SplineInterpolationDataTextArea.Value = {'Error: At least two data points are required for spline interpolation.'};
        cla(app.UIAxesSpline); % Clear graph on error
        return;
    end

    % 2. Sort data (crucial for interpolation functions like interp1)
    [x_data, sort_idx] = sort(x_data);
    y_data = y_data(sort_idx);

    % --- SPLINE INTERPOLATION CALCULATION ---
    % Perform spline interpolation using MATLAB's interp1 function.
    % The 'spline' method performs cubic spline interpolation.
    y_interp = interp1(x_data, y_data, x_interp, 'spline');

    % Check if interpolation resulted in NaN (e.g., due to extrapolation, as 'spline' does not extrapolate by default)
    if isnan(y_interp)
        app.SplineInterpolationDataTextArea.Value = {'Warning: Interpolated X value is outside the range of provided data. Result is NaN (spline does not extrapolate by default).'};
        cla(app.UIAxesSpline); % Clear graph
        return;
    end
    
    % --- DISPLAY RESULTS ---
    % Display the interpolated Y value in the SplineInterpolationDataTextArea
    output_str = {};
    output_str{end+1} = '--- Spline Interpolation Results ---';
    output_str{end+1} = ['Interpolated Y for X = ', num2str(x_interp), ' is: ', num2str(y_interp)];
    output_str{end+1} = '';
    output_str{end+1} = 'Input Data:';
    output_str{end+1} = ['X = [', num2str(x_data), ']'];
    output_str{end+1} = ['Y = [', num2str(y_data), ']'];
    output_str{end+1} = '';
    output_str{end+1} = 'Note: Spline interpolation produces a piecewise polynomial. No single equation is generated.';
    app.SplineInterpolationDataTextArea.Value = output_str;

    % --- PLOTTING THE GRAPH ---
    cla(app.UIAxesSpline); % Clear the axes before plotting new data

    % Define plot range for the spline curve
    x_plot_min = min(x_data) - 0.1 * (max(x_data) - min(x_data));
    x_plot_max = max(x_data) + 0.1 * (max(x_data) - min(x_data));
    
    % Handle cases where x_data might be a single point or very narrow range
    if isempty(x_data) || (max(x_data) - min(x_data) == 0)
        x_plot_min = x_data(1) - 1;
        x_plot_max = x_data(1) + 1;
    elseif isnan(x_plot_min) || isnan(x_plot_max)
        % Fallback for edge cases where scaling might result in NaN
        x_plot_min = min(x_data) - 1;
        x_plot_max = max(x_data) + 1;
    end

    % Generate a finer set of points for plotting the smooth spline curve
    x_vals_plot_spline = linspace(x_plot_min, x_plot_max, 200);
    y_vals_plot_spline = interp1(x_data, y_data, x_vals_plot_spline, 'spline');

    % Plot the smooth spline interpolation curve
    plot(app.UIAxesSpline, x_vals_plot_spline, y_vals_plot_spline, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Spline Interpolation');
    hold(app.UIAxesSpline, 'on'); % Keep the plot for adding more elements

    % Plot original data points as markers
    plot(app.UIAxesSpline, x_data, y_data, 'ro', 'MarkerFaceColor', 'r', 'DisplayName', 'Data Points');

    % Plot the interpolated point
    plot(app.UIAxesSpline, x_interp, y_interp, 'gx', 'MarkerSize', 10, 'LineWidth', 1.5, 'DisplayName', 'Interpolated Point');
    
    % Set axis labels and title
    title(app.UIAxesSpline, 'Spline Interpolation Graph');
    xlabel(app.UIAxesSpline, 'X');
    ylabel(app.UIAxesSpline, 'Y');
    grid(app.UIAxesSpline, 'on');

    % Add legend
    legend(app.UIAxesSpline, 'Location', 'best');
    
    hold(app.UIAxesSpline, 'off'); % Release the hold on the axes

catch ME
    % Handle errors and display them in the SplineInterpolationDataTextArea
    app.SplineInterpolationDataTextArea.Value = {['Error: ', ME.message]};
    cla(app.UIAxesSpline); 
end
        end

        % Button pushed function: ComputeRootButton
        function ComputeRootButtonPushed(app, event)
            % Read inputs
            f_str = app.FunctiongivenEditField.Value;
            try
                f = str2func(['@(x)', f_str]);  % Convert to anonymous function
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                tolerance = app.BisectionToleranceEditField.Value;
                maxiIter = app.BisectionMaxiterationEditField.Value;
            
                % Check if f(a) and f(b) have opposite signs
                fa = f(a);
                fb = f(b);
                if fa * fb > 0
                    app.BisectionDataTextArea.Value = {'Error: f(a) and f(b) must have opposite signs.'};
                    return;
                end
            
                % Bisection loop
                iter = 0;
                data = [];
                while (b - a)/2 > tolerance && iter < maxiIter
                    c = (a + b)/2;
                    fc = f(c);
                    iter = iter + 1;
            
                    data(end+1,:) = [iter, a, b, c, fc]; %#ok<AGROW>
            
                    if abs(fc) < eps  % Better floating-point comparison
                        break;
                    elseif fa * fc < 0
                        b = c;
                        fb = fc;
                    else
                        a = c;
                        fa = fc;
                    end
                end
            
                root = (a + b)/2;
                app.BisectionDataTextArea.Value = {sprintf('Root ≈ %.6f after %d iterations', root, iter)};
            
                app.rootbisectionEditField.Value = root;
                app.BisectionIterationEditField.Value = iter;
            
                % Update table
                header = sprintf('%5s\t%10s\t%15s\t%15s\t%15s', 'Iter', 'a', 'b', 'c', 'f(c)');
                rows = cell(size(data,1),1);
                for i = 1:size(data,1)
                    rows{i} = sprintf('%5d\t%10.6f\t%10.6f\t%10.6f\t%10.6f', data(i,1), data(i,2), data(i,3), data(i,4), data(i,5));
                end
                app.BisectionDataTextArea.Value = [{header}; rows];
            
            
                % ======== PLOT FUNCTION AND ROOT ========
                x_vals = linspace(a, b, 400);  % Use final interval for plotting
                y_vals = arrayfun(f, x_vals);
            
                cla(app.UIAxes);
                plot(app.UIAxes, x_vals, y_vals, 'b-', 'LineWidth', 1.5);
                hold(app.UIAxes, 'on');
            
                % Plot syntax
                plot(app.UIAxes, a, fa, 'ks', 'MarkerSize', 8, 'DisplayName', 'a');
                plot(app.UIAxes, b, fb, 'ks', 'MarkerSize', 8, 'DisplayName', 'b');
                plot(app.UIAxes, root, f(root), 'ro', 'MarkerSize', 8, 'DisplayName', 'Root');
            
                % Labels
                title(app.UIAxes, 'Function Plot with Root');
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'f(x)');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
            
            catch ME
                app.BisectionDataTextArea.Value = {['Error: ', ME.message]};
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             % Get True Value and Approximate Value
            exactValue = app.ExactValueEditField.Value;
            approximateValue = app.ApproximateValueEditField.Value;

             % Calculate Absolute Error
            AbsoluteError = abs(exactValue - approximateValue);

             % Calculate Relative Error
            if exactValue ~= 0
                RelativeError = AbsoluteError / abs(exactValue);
            else
                RelativeError = NaN; % If true value is zero, relative error is undefined
            end
            % Display the errors in their respective fields
            app.AbsoluteErrorEditField.Value = num2str(AbsoluteError);
            app.RelativeErrorEditField.Value = num2str(RelativeError);
        end

        % Callback function
        function CalculateButton_2Pushed(app, event)
 funcStr = app.FunctionEditField.Value; % Function input as string
            a = app.aEditField.Value;              % Lower limit of integration
            b = app.bEditField.Value;              % Upper limit of integration

            % Convert the function input to a function handle
            func = str2func([' @(x) ', funcStr]); 

            % Calculate the exact integral using integral function
            result2 = integral(func, a, b);

            % Display the result in the TrapezoidalApproximationEditField
            app.ExactValueEditField_2.Value = num2str(result2);
    
        end

        % Callback function
        function CalculateButton_3Pushed(app, event)
           % Read input values
            funcStr = app.FunctionEditField.Value; % Function input as string
            a = app.aEditField.Value;              % Lower limit of integration
            b = app.bEditField.Value;              % Upper limit of integration
            n = app.nEditField.Value;              % Number of subdivisions

            % Convert the function input to a function handle
            func = str2func(['@(x)', funcStr]);

            % Read selected method
            method = app.QuadratureDropDown.Value;

            % Apply selected rule
            switch method
            case 'Midpoint Rule'
            h = (b - a) / n;  % Step size
            x_mid = a + h/2 : h : b - h/2; % Midpoints
            y_mid = func(x_mid);  % Evaluate the function at midpoints
            result = h * sum(y_mid); % Apply Composite Midpoint Rule
   
            case 'Trapezoidal Rule'
            h = (b - a) / n;  % Step size
            x = a:h:b;        % Create x values
            y = func(x);      % Evaluate the function at x values
            result = (h / 2) * (y(1) + 2 * sum(y(2:end-1)) + y(end)); % Apply the trapezoidal rule
           
            case 'Simpsons Rule'
            h = (b - a) / n;  % Step size
            x = a:h:b;        % Create x values
            y = func(x);      % Evaluate the function at x values
            result = (h / 3) * (y(1) + 4 * sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2)) + y(end)); % Apply the Simpson's rule
         
            end

            % Display the result 
            app.AnswerEditField.Value = num2str(result);
        end

        % Button pushed function: PressButton
        function PressButtonPushed(app, event)
            % Get user inputs
            t0 = app.t0EditField.Value; % Initial time
            y0 = app.y0EditField.Value; % Initial value of y
            h = app.hEditField.Value;   % Step size
            n = app.nEditField_2.Value;   % Number of steps
            funcStr = app.FunctionEditField_3.Value;  % Differential equation function


            % Create a function handle from the string input
            func = str2func(['@(t, y) ' funcStr]);  % Create a function handle for the differential equation

            % Prepare arrays to store time and solution values
            tvals = t0;  % Initial time value
            yvals = y0;  % Initial solution value

           % Perform Euler's method
            t = t0;
            y = y0;
            for i = 1:n
                % Evaluate the differential equation at current t and y
                dydt = func(t, y);  % Evaluate the function at the current point
                
                % Update solution using Euler's method
                y = y + h * dydt;  % Euler update
                t = t + h;         % Increment time
                
                % Store the results
                t_vals = [tvals, t];
                y_vals = [yvals, y];
            end

            % Display results in the ApproximateEditField
            app.ApproximateEditField.Value = y_vals(end); % Approximate value of y at the final step

            % Optionally display the results in the Command Window
            disp('Time Values:');
            disp(t_vals);
            disp('Solution Values:');
            disp(y_vals);
        end

        % Callback function: not associated with a component
        function CalculateButton_2Pushed2(app, event)
            funcStr = app.FunctionEditField_4.Value; % Function input as string
            a = app.aEditField_2.Value;              % Lower limit of integration
            b = app.bEditField_2.Value;              % Upper limit of integration

            % Convert the function input to a function handle
            func = str2func(['@(x)', funcStr]); 

            % Calculate the exact integral using integral function
            result2 = integral(func, a, b);

            % Display the result in the TrapezoidalApproximationEditField
            app.ExactValueEditField_2.Value = num2str(result2);
        end

        % Callback function: not associated with a component
        function CalculateButton_3Pushed2(app, event)
             % Read input values
            funcStr = app.FunctionEditField_4.Value; % Function input as string
            a = app.aEditField_2.Value;              % Lower limit of integration
            b = app.bEditField_2.Value;              % Upper limit of integration
            n = app.nEditField_3.Value;              % Number of subdivisions

            % Convert the function input to a function handle
            func = str2func(['@(x)', funcStr]);

            % Read selected method
            method = app.QuadratureDropDown.Value;

            % Apply selected rule
            switch method
                case 'Midpoint Rule'
                    h = (b - a) / n;  % Step size
                    x_mid = a + h/2 : h : b - h/2; % Midpoints
                    y_mid = func(x_mid);  % Evaluate the function at midpoints
                    result = h * sum(y_mid); % Apply Composite Midpoint Rule
   
                case 'Trapezoidal Rule'
                    h = (b - a) / n;  % Step size
                    x = a:h:b;        % Create x values
                    y = func(x);      % Evaluate the function at x values
                    result = (h / 2) * (y(1) + 2 * sum(y(2:end-1)) + y(end)); % Apply the trapezoidal rule
           
                case 'Simpsons Rule'
                    h = (b - a) / n;  % Step size
                    x = a:h:b;        % Create x values
                    y = func(x);      % Evaluate the function at x values
                    result = (h / 3) * (y(1) + 4 * sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2)) + y(end)); % Apply the Simpson's rule
         
            end

            % Display the result 
            app.AnswerEditField.Value = num2str(result);
        end

        % Button pushed function: ComputeButtonTrapezoidal
        function ComputeButtonTrapezoidalPushed(app, event)
            % --- 1. Get user inputs
            fx_str = app.fxEditField.Value;
            a = app.LowerlimitaEditField.Value;
            b = app.UpperlimitbEditField.Value;
            n = app.nEditField_3.Value;
            
            % --- 2. Validate input
            if a >= b
                app.ResultsTextArea.Value = 'Error: Lower limit must be less than upper limit.';
                return;
            end
            
            if n <= 0 || mod(n,1) ~= 0
                app.ResultsTextArea.Value = 'Error: n must be a positive integer.';
                return;
            end
            
            
            % --- 3. Convert string to function handle
            try
                f = str2func(['@(x)' fx_str]);
                test = f(1); % validate function
            catch
                app.ResultsTextArea.Value = 'Error: Invalid function input.';
                return;
            end
            
            % --- 4. Prepare values
            h = (b - a) / n;
            x = linspace(a, b, n+1);
            y = f(x);
            approx = (h/2) * (y(1) + 2*sum(y(2:end-1)) + y(end));  % Trapezoidal Rule
            
            % --- 5. Compute exact integral using MATLAB's integral()
            try
                exact = integral(f, a, b);
                error_val = abs(approx - exact);
            catch
                exact = NaN;
                error_val = NaN;
            end
            
            % --- 6. Display results
            result_str = sprintf(['Method: Trapezoidal Rule\n' ...
                                  'Numerical Integral ≈ %.6f\n'], approx);
            
            if ~isnan(exact)
                result_str = sprintf(['%sExact Integral = %.6f\nAbsolute Error = %.6f'], ...
                                      result_str, exact, error_val);
            else
                result_str = sprintf('%sExact Integral = Not available', result_str);
            end
            
            app.ResultsTextArea.Value = result_str;
            
            % --- 7. Plot the function and trapezoids
            cla(app.UIAxes_2);
            fplot(app.UIAxes_2, f, [a, b], 'LineWidth', 1.5, 'Color', 'b');
            hold(app.UIAxes_2, 'on');
            
            for i = 1:n
                x_fill = [x(i), x(i), x(i+1), x(i+1)];
                y_fill = [0, f(x(i)), f(x(i+1)), 0];
                fill(app.UIAxes_2, x_fill, y_fill, 'r', 'FaceAlpha', 0.3, 'EdgeColor', 'none');
            end
            
            hold(app.UIAxes_2, 'off');
            grid(app.UIAxes_2, 'on');
            title(app.UIAxes_2, 'Numerical Integration (Trapezoidal Rule)');
            xlabel(app.UIAxes_2, 'x');
            ylabel(app.UIAxes_2, 'f(x)');
        end

        % Button pushed function: ComputeButtonSimpson
        function ComputeButtonSimpsonPushed(app, event)
            % --- 1. Get user inputs from Simpson's Tab
            fx_str = app.fxEditField_2.Value;
            a = app.LowerlimitaEditField_2.Value;
            b = app.UpperlimitbEditField_2.Value;
            n = app.nEditField_4.Value;
            
            % --- 2. Validate input
            if a >= b
                app.ResultsTextArea_2.Value = 'Error: Lower limit must be less than upper limit.';
                return;
            end
            
            if n <= 0 || mod(n,1) ~= 0
                app.ResultsTextArea_2.Value = 'Error: n must be a positive integer.';
                return;
            end
            
            if mod(n, 2) ~= 0
                % Show note inside results and stop
                app.ResultsTextArea_2.Value = 'Note: n must be even for Simpson''s Rule!';
                return;
            end
            
            % --- 3. Convert string to function handle
            try
                f = str2func(['@(x)' fx_str]);
                test = f(1); % Validate function
            catch
                app.ResultsTextArea_2.Value = 'Error: Invalid function input.';
                return;
            end
            
            % --- 4. Prepare values
            h = (b - a) / n;
            x = linspace(a, b, n + 1);
            y = f(x);
            
            % --- 5. Compute Simpson's Rule
            approx = (h/3) * (y(1) + ...
                              4 * sum(y(2:2:n)) + ...
                              2 * sum(y(3:2:n-1)) + ...
                              y(end));
            
            % --- 6. Compute exact integral (if possible)
            try
                exact = integral(f, a, b);
                error_val = abs(approx - exact);
            catch
                exact = NaN;
                error_val = NaN;
            end
            
            % --- 7. Display results in ResultsTextArea_2
            result_str = sprintf(['Method: Simpson''s Rule\n' ...
                                  'Numerical Integral ≈ %.6f\n'], approx);
            
            if ~isnan(exact)
                result_str = sprintf(['%sExact Integral = %.6f\nAbsolute Error = %.6f'], ...
                                      result_str, exact, error_val);
            else
                result_str = sprintf('%sExact Integral = Not available', result_str);
            end
            
            app.ResultsTextArea_2.Value = result_str;
            
            % --- 8. Plot the function and sample points on UIAxes_3
            cla(app.UIAxes_3);
            fplot(app.UIAxes_3, f, [a, b], 'LineWidth', 1.5, 'Color', 'b');
            hold(app.UIAxes_3, 'on');
            
            % Optional: plot the nodes used in Simpson's Rule
            plot(app.UIAxes_3, x, y, 'ro--', 'LineWidth', 1.2);
            
            hold(app.UIAxes_3, 'off');
            grid(app.UIAxes_3, 'on');
            title(app.UIAxes_3, 'Numerical Integration (Simpson''s Rule)');
            xlabel(app.UIAxes_3, 'x');
            ylabel(app.UIAxes_3, 'f(x)');

        end

        % Button pushed function: ComputeButtonMidpoint
        function ComputeButtonMidpointPushed(app, event)
            % --- 1. Get user inputs
            fx_str = app.fxEditField_3.Value;
            a = app.LowerlimitaEditField_3.Value;
            b = app.UpperlimitbEditField_3.Value;
            n = app.nEditField_5.Value;
            
            % --- 2. Validate input
            if a >= b
                app.ResultsTextArea_3.Value = 'Error: Lower limit must be less than upper limit.';
                return;
            end
            
            if n <= 0 || mod(n,1) ~= 0
                app.ResultsTextArea_3.Value = 'Error: n must be a positive integer.';
                return;
            end
            
            % --- 3. Convert string to function handle
            try
                f = str2func(['@(x)' fx_str]);
                test = f(1); % test validity
            catch
                app.ResultsTextArea_3.Value = 'Error: Invalid function input.';
                return;
            end
            
            % --- 4. Midpoint Rule calculation
            h = (b - a) / n;
            
            if n == 1
                % Single midpoint rule
                xm = (a + b) / 2;
                approx = (b - a) * f(xm);
            else
                % Composite midpoint rule
                xm = a + h/2 : h : b - h/2;
                approx = h * sum(f(xm));
            end
            
            % --- 5. Exact integral (if possible)
            try
                exact = integral(f, a, b);
                error_val = abs(approx - exact);
            catch
                exact = NaN;
                error_val = NaN;
            end
            
            % --- 6. Display results
            method_type = 'Composite Midpoint Rule';
            if n == 1
                method_type = 'Single Midpoint Rule';
            end
            
            result_str = sprintf(['Method: %s\n' ...
                                  'Numerical Integral ≈ %.6f\n'], method_type, approx);
            
            if ~isnan(exact)
                result_str = sprintf(['%sExact Integral = %.6f\nAbsolute Error = %.6f'], ...
                                      result_str, exact, error_val);
            else
                result_str = sprintf('%sExact Integral = Not available', result_str);
            end
            
            app.ResultsTextArea_3.Value = result_str;
            
            % --- 7. Plot function and rectangles
            cla(app.UIAxes_4);
            fplot(app.UIAxes_4, f, [a, b], 'b', 'LineWidth', 1.5);
            hold(app.UIAxes_4, 'on');
            
            if n == 1
                % Single midpoint rectangle
                y_mid = f((a + b)/2);
                x_fill = [a, a, b, b];
                y_fill = [0, y_mid, y_mid, 0];
                fill(app.UIAxes_4, x_fill, y_fill, 'g', 'FaceAlpha', 0.3, 'EdgeColor', 'none');
                plot(app.UIAxes_4, (a + b)/2, y_mid, 'ro', 'MarkerSize', 8);
            else
                % Composite rectangles
                for i = 1:n
                    xm = a + (i - 0.5)*h;
                    y_m = f(xm);
                    x_fill = [xm - h/2, xm - h/2, xm + h/2, xm + h/2];
                    y_fill = [0, y_m, y_m, 0];
                    fill(app.UIAxes_4, x_fill, y_fill, 'g', 'FaceAlpha', 0.3, 'EdgeColor', 'none');
                end
            end
            
            hold(app.UIAxes_4, 'off');
            grid(app.UIAxes_4, 'on');
            title(app.UIAxes_4, method_type);
            xlabel(app.UIAxes_4, 'x');
            ylabel(app.UIAxes_4, 'f(x)');
        end

        % Button pushed function: ComputeRoot
        function ComputeRootButtonPushed2(app, event)
            % Get user inputs
            x0 = app.InitialguessEditField.Value; % Initial guess
            tol = app.ErrortoleranceEditField.Value;
            max_iter = app.MaxIterationsEditField.Value;
            funcStr = app.FunctionEditField_4.Value;
            
            % Convert string to function handle
            try
                f = str2func(['@(x)', funcStr]);
                f(1); % Test it
            catch
                uialert(app.UIFigure, 'Invalid function input. Please enter a valid function of x.', 'Error');
                return;
            end
            
            % --- Newton-Raphson Method (defined inside this function)
            function data = newton_method(f, x0, tol, max_iter)
                h = 1e-6;
                data = struct('Iteration', {}, 'x_n', {}, 'f_xn', {}, 'f_prime_xn', {}, 'x_next', {}, 'error', {});
            
                for i = 1:max_iter
                    f_val = f(x0);
                    f_prime = (f(x0 + h) - f(x0 - h)) / (2 * h);
            
                    if abs(f_prime) < 1e-12
                        error('Derivative too small. Risk of division by zero.');
                    end
            
                    x1 = x0 - f_val / f_prime;
                    err = abs(x1 - x0);
            
                    data(i).Iteration = i;
                    data(i).x_n = x0;
                    data(i).f_xn = f_val;
                    data(i).f_prime_xn = f_prime;
                    data(i).x_next = x1;
                    data(i).error = err;
            
                    if err < tol
                        break;
                    end
            
                    x0 = x1;
                end
            end
            
            % Run the method and display results
            try
                results = newton_method(f, x0, tol, max_iter);
                app.ResultTable.ColumnName = {'Iteration', 'x_n', 'f(x_n)', 'f''(x_n)', 'x_{n+1}', '|x_{n+1} - x_n|'};
                app.ResultTable.Data = struct2table(results);
            
                % Display final root and iteration number in the Edit Field
                last = results(end);
                app.AnswerEditField.Value = sprintf('x = %.6f (found at iteration %d)', last.x_next, last.Iteration);
            
                % ---- Simple function plot on UIAxes2 ----
                x_min = last.x_next - 5;
                x_max = last.x_next + 5;
                x_plot = linspace(x_min, x_max, 400);
                y_plot = arrayfun(f, x_plot);
                
                cla(app.UIAxes2);
                plot(app.UIAxes2, x_plot, y_plot, 'b-', 'LineWidth', 1.5); % function
                hold(app.UIAxes2, 'on');
                
                % Mark the root
                plot(app.UIAxes2, last.x_next, f(last.x_next), 'ro', 'MarkerSize', 8, 'DisplayName', 'Root');
                
                % Labels
                title(app.UIAxes2, 'Function Plot with Root');
                xlabel(app.UIAxes2, 'x');
                ylabel(app.UIAxes2, 'f(x)');
                grid(app.UIAxes2, 'on');
                hold(app.UIAxes2, 'off');

            
            catch ME
                uialert(app.UIFigure, ME.message, 'Calculation Error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WELCOMETab = uitab(app.TabGroup);
            app.WELCOMETab.Title = 'WELCOME';
            app.Image4 = uiimage(app.WELCOMETab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '8894154d-9fb6-4b15-b137-decce52702ae.jpg');
            app.LETSSTARTLabel = uilabel(app.WELCOMETab);
            app.LETSSTARTLabel.Text = 'LET''S START -->';
            app.BISECTIONMETHODTab = uitab(app.TabGroup);
            app.BISECTIONMETHODTab.Title = 'BISECTION METHOD';
            app.UIAxes = uiaxes(app.BISECTIONMETHODTab);
            title(app.UIAxes, 'Function graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ComputeRootButton = uibutton(app.BISECTIONMETHODTab, 'push');
            app.ComputeRootButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeRootButtonPushed, true);
            app.ComputeRootButton.Text = 'Compute Root';
            app.ANSWERLabel = uilabel(app.BISECTIONMETHODTab);
            app.ANSWERLabel.Text = 'ANSWER:';
            app.egx22x1Label_2 = uilabel(app.BISECTIONMETHODTab);
            app.egx22x1Label_2.Text = 'e.g.: x^2-2*x+1';
            app.InserttheintervalabLabel = uilabel(app.BISECTIONMETHODTab);
            app.InserttheintervalabLabel.Text = 'Insert the  interval [a, b]';
            app.eg00001Label_2 = uilabel(app.BISECTIONMETHODTab);
            app.eg00001Label_2.Text = 'e.g.: 0.0001';
            app.leveloferrorthatisacceptableLabel_2 = uilabel(app.BISECTIONMETHODTab);
            app.leveloferrorthatisacceptableLabel_2.Text = 'level of error that is acceptable ';
            app.maximumiterationcanbedoneLabel = uilabel(app.BISECTIONMETHODTab);
            app.maximumiterationcanbedoneLabel.Text = 'maximum iteration can be done';
            app.FunctiongivenEditFieldLabel = uilabel(app.BISECTIONMETHODTab);
            app.FunctiongivenEditFieldLabel.Text = 'Function given';
            app.FunctiongivenEditField = uieditfield(app.BISECTIONMETHODTab, 'text');
            app.aEditFieldLabel = uilabel(app.BISECTIONMETHODTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.BISECTIONMETHODTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.BISECTIONMETHODTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.BISECTIONMETHODTab, 'numeric');
            app.BisectionToleranceEditFieldLabel = uilabel(app.BISECTIONMETHODTab);
            app.BisectionToleranceEditFieldLabel.Text = 'Bisection Tolerance';
            app.BisectionToleranceEditField = uieditfield(app.BISECTIONMETHODTab, 'numeric');
            app.BisectionMaxiterationEditFieldLabel = uilabel(app.BISECTIONMETHODTab);
            app.BisectionMaxiterationEditFieldLabel.Text = 'Bisection Max iteration';
            app.BisectionMaxiterationEditField = uieditfield(app.BISECTIONMETHODTab, 'numeric');
            app.rootbisectionEditFieldLabel = uilabel(app.BISECTIONMETHODTab);
            app.rootbisectionEditFieldLabel.Text = 'root bisection';
            app.rootbisectionEditField = uieditfield(app.BISECTIONMETHODTab, 'numeric');
            app.BisectionIterationEditFieldLabel = uilabel(app.BISECTIONMETHODTab);
            app.BisectionIterationEditFieldLabel.Text = 'Bisection Iteration';
            app.BisectionIterationEditField = uieditfield(app.BISECTIONMETHODTab, 'numeric');
            app.BisectionDataTextArea = uitextarea(app.BISECTIONMETHODTab);
            app.BisectionDataTextAreaLabel = uilabel(app.BISECTIONMETHODTab);
            app.BisectionDataTextAreaLabel.Text = 'Bisection Data';
            app.Image5 = uiimage(app.BISECTIONMETHODTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '4bdf7fec-3f94-40e3-8984-7ad467cd09a2.jpg');
            app.SECANTMETHODTab = uitab(app.TabGroup);
            app.SECANTMETHODTab.Title = 'SECANT METHOD';
            app.UIAxesSecant = uiaxes(app.SECANTMETHODTab);
            title(app.UIAxesSecant, 'Plot')
            xlabel(app.UIAxesSecant, 'X')
            ylabel(app.UIAxesSecant, 'Y')
            zlabel(app.UIAxesSecant, 'Z')
            app.RootEditFieldLabel = uilabel(app.SECANTMETHODTab);
            app.RootEditFieldLabel.Text = 'Root';
            app.RootEditField = uieditfield(app.SECANTMETHODTab, 'numeric');
            app.IterationEditFieldLabel = uilabel(app.SECANTMETHODTab);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.SECANTMETHODTab, 'numeric');
            app.DataTextAreaLabel = uilabel(app.SECANTMETHODTab);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.SECANTMETHODTab);
            app.ComputeButton = uibutton(app.SECANTMETHODTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.egx22x1Label = uilabel(app.SECANTMETHODTab);
            app.egx22x1Label.Text = 'e.g.: x^2-2x+1';
            app.InserttheinitialguessLabel = uilabel(app.SECANTMETHODTab);
            app.InserttheinitialguessLabel.Text = 'Insert the initial guess :';
            app.leveloferrorthatisacceptableLabel = uilabel(app.SECANTMETHODTab);
            app.leveloferrorthatisacceptableLabel.Text = 'level of error that is acceptable ';
            app.eg00001Label = uilabel(app.SECANTMETHODTab);
            app.eg00001Label.Text = 'e.g.: 0.0001';
            app.maximumiterationLabel = uilabel(app.SECANTMETHODTab);
            app.maximumiterationLabel.Text = 'maximum iteration';
            app.FunctionEditFieldLabel = uilabel(app.SECANTMETHODTab);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.SECANTMETHODTab, 'text');
            app.x0EditFieldLabel = uilabel(app.SECANTMETHODTab);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.SECANTMETHODTab, 'numeric');
            app.x1EditFieldLabel = uilabel(app.SECANTMETHODTab);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.SECANTMETHODTab, 'numeric');
            app.ToleranceEditFieldLabel = uilabel(app.SECANTMETHODTab);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.SECANTMETHODTab, 'numeric');
            app.MaxiterationEditFieldLabel = uilabel(app.SECANTMETHODTab);
            app.MaxiterationEditFieldLabel.Text = 'Max iteration';
            app.MaxiterationEditField = uieditfield(app.SECANTMETHODTab, 'numeric');
            app.ANSWERLabel_2 = uilabel(app.SECANTMETHODTab);
            app.ANSWERLabel_2.Text = 'ANSWER:';
            app.x0andx1cantbe0Label = uilabel(app.SECANTMETHODTab);
            app.x0andx1cantbe0Label.Text = 'x0 and x1 can''t be 0';
            app.Image6 = uiimage(app.SECANTMETHODTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '43337bc4-2ddd-4fe0-b1ba-9309d8203172.jpg');
            app.SPLINEINTERPOLATIONTab = uitab(app.TabGroup);
            app.SPLINEINTERPOLATIONTab.Title = 'SPLINE INTERPOLATION';
            app.UIAxesSpline = uiaxes(app.SPLINEINTERPOLATIONTab);
            title(app.UIAxesSpline, 'Spline Interpolation Graph')
            xlabel(app.UIAxesSpline, 'X')
            ylabel(app.UIAxesSpline, 'Y')
            zlabel(app.UIAxesSpline, 'Z')
            app.InterpolateButton = uibutton(app.SPLINEINTERPOLATIONTab, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.eg123or123Label = uilabel(app.SPLINEINTERPOLATIONTab);
            app.eg123or123Label.Text = 'e.g.: 1, 2, 3 or 1 2 3';
            app.eg123or123Label_2 = uilabel(app.SPLINEINTERPOLATIONTab);
            app.eg123or123Label_2.Text = 'e.g.: 1, 2, 3 or 1 2 3';
            app.FindyatxLabel = uilabel(app.SPLINEINTERPOLATIONTab);
            app.FindyatxLabel.Text = 'Find y at  x ';
            app.InserttheXandYvaluesLabel = uilabel(app.SPLINEINTERPOLATIONTab);
            app.InserttheXandYvaluesLabel.Text = 'Insert the X and Y values';
            app.xmustbeinbetweenXValuesrangeLabel = uilabel(app.SPLINEINTERPOLATIONTab);
            app.xmustbeinbetweenXValuesrangeLabel.Text = {'x must be in between'; ' X Values range'};
            app.XValuesEditFieldLabel = uilabel(app.SPLINEINTERPOLATIONTab);
            app.XValuesEditFieldLabel.Text = 'X Values';
            app.XValuesEditField = uieditfield(app.SPLINEINTERPOLATIONTab, 'text');
            app.YValuesEditFieldLabel = uilabel(app.SPLINEINTERPOLATIONTab);
            app.YValuesEditFieldLabel.Text = 'Y Values';
            app.YValuesEditField = uieditfield(app.SPLINEINTERPOLATIONTab, 'text');
            app.XInterpEditFieldLabel = uilabel(app.SPLINEINTERPOLATIONTab);
            app.XInterpEditFieldLabel.Text = 'X Interp';
            app.XInterpEditField = uieditfield(app.SPLINEINTERPOLATIONTab, 'text');
            app.SplineInterpolationDataLabel = uilabel(app.SPLINEINTERPOLATIONTab);
            app.SplineInterpolationDataLabel.Text = {'Spline Interpolation '; 'Data'};
            app.SplineInterpolationDataTextArea = uitextarea(app.SPLINEINTERPOLATIONTab);
            app.Image3 = uiimage(app.SPLINEINTERPOLATIONTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '974f89fd-8822-44be-9a32-f0509831a1f3.jpg');
            app.ERRORTab = uitab(app.TabGroup);
            app.ERRORTab.Title = 'ERROR';
            app.Image3_2 = uiimage(app.ERRORTab);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, '974f89fd-8822-44be-9a32-f0509831a1f3.jpg');
            app.ExactValueEditFieldLabel = uilabel(app.ERRORTab);
            app.ExactValueEditFieldLabel.Text = 'Exact Value';
            app.ExactValueEditField = uieditfield(app.ERRORTab, 'numeric');
            app.ApproximateValueEditFieldLabel = uilabel(app.ERRORTab);
            app.ApproximateValueEditFieldLabel.Text = 'Approximate Value';
            app.ApproximateValueEditField = uieditfield(app.ERRORTab, 'numeric');
            app.CalculateButton = uibutton(app.ERRORTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ANSWERLabel_3 = uilabel(app.ERRORTab);
            app.ANSWERLabel_3.Text = 'ANSWER';
            app.AbsoluteErrorEditFieldLabel = uilabel(app.ERRORTab);
            app.AbsoluteErrorEditFieldLabel.Text = 'Absolute Error';
            app.AbsoluteErrorEditField = uieditfield(app.ERRORTab, 'text');
            app.RelativeErrorEditFieldLabel = uilabel(app.ERRORTab);
            app.RelativeErrorEditFieldLabel.Text = 'Relative Error';
            app.RelativeErrorEditField = uieditfield(app.ERRORTab, 'text');
            app.EULERTab = uitab(app.TabGroup);
            app.EULERTab.Title = 'EULER';
            app.Image3_4 = uiimage(app.EULERTab);
            app.Image3_4.ImageSource = fullfile(pathToMLAPP, '974f89fd-8822-44be-9a32-f0509831a1f3.jpg');
            app.FunctionEditField_3Label = uilabel(app.EULERTab);
            app.FunctionEditField_3Label.Text = 'Function';
            app.FunctionEditField_3 = uieditfield(app.EULERTab, 'text');
            app.InitialtimeLabel = uilabel(app.EULERTab);
            app.InitialtimeLabel.Text = 'Initial time';
            app.t0EditFieldLabel = uilabel(app.EULERTab);
            app.t0EditFieldLabel.Text = 't0';
            app.t0EditField = uieditfield(app.EULERTab, 'numeric');
            app.InitialvalueLabel = uilabel(app.EULERTab);
            app.InitialvalueLabel.Text = 'Initial value';
            app.y0EditFieldLabel = uilabel(app.EULERTab);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.EULERTab, 'numeric');
            app.StepsizeLabel = uilabel(app.EULERTab);
            app.StepsizeLabel.Text = 'Step size';
            app.hEditFieldLabel = uilabel(app.EULERTab);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.EULERTab, 'numeric');
            app.NumberofstepsLabel = uilabel(app.EULERTab);
            app.NumberofstepsLabel.Text = 'Number of steps';
            app.nEditField_2Label = uilabel(app.EULERTab);
            app.nEditField_2Label.Text = 'n';
            app.nEditField_2 = uieditfield(app.EULERTab, 'numeric');
            app.PressButton = uibutton(app.EULERTab, 'push');
            app.PressButton.ButtonPushedFcn = createCallbackFcn(app, @PressButtonPushed, true);
            app.PressButton.Text = 'Press';
            app.ApproximateEditFieldLabel = uilabel(app.EULERTab);
            app.ApproximateEditFieldLabel.Text = 'Approximate';
            app.ApproximateEditField = uieditfield(app.EULERTab, 'numeric');
            app.NotesUsedifferentialeqnLabel = uilabel(app.EULERTab);
            app.NotesUsedifferentialeqnLabel.Text = {'Notes:'; 'Use differential eqn'};
            app.Examplety1Label = uilabel(app.EULERTab);
            app.Examplety1Label.Text = 'Example: t+y-1';
            app.TRAPEZOIDALRULETab = uitab(app.TabGroup);
            app.TRAPEZOIDALRULETab.Title = 'TRAPEZOIDAL RULE';
            app.UIAxes_2 = uiaxes(app.TRAPEZOIDALRULETab);
            title(app.UIAxes_2, 'TRAPEZOIDAL RULE')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ComputeButtonTrapezoidal = uibutton(app.TRAPEZOIDALRULETab, 'push');
            app.ComputeButtonTrapezoidal.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonTrapezoidalPushed, true);
            app.ComputeButtonTrapezoidal.Text = 'Compute';
            app.expx23x3Label = uilabel(app.TRAPEZOIDALRULETab);
            app.expx23x3Label.Text = 'exp: x.^2 + 3*x -3';
            app.NumberofsubintervalsBasicn1Compositen1Label = uilabel(app.TRAPEZOIDALRULETab);
            app.NumberofsubintervalsBasicn1Compositen1Label.Text = {'Number of subintervals:'; 'Basic: n = 1'; 'Composite: n > 1'};
            app.TheendoftheintervalLabel = uilabel(app.TRAPEZOIDALRULETab);
            app.TheendoftheintervalLabel.Text = 'The end of the interval';
            app.ThestartoftheintervalLabel = uilabel(app.TRAPEZOIDALRULETab);
            app.ThestartoftheintervalLabel.Text = 'The start of the interval';
            app.fxEditFieldLabel = uilabel(app.TRAPEZOIDALRULETab);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.TRAPEZOIDALRULETab, 'text');
            app.LowerlimitaEditFieldLabel = uilabel(app.TRAPEZOIDALRULETab);
            app.LowerlimitaEditFieldLabel.Text = 'Lower limit, a';
            app.LowerlimitaEditField = uieditfield(app.TRAPEZOIDALRULETab, 'numeric');
            app.UpperlimitbEditFieldLabel = uilabel(app.TRAPEZOIDALRULETab);
            app.UpperlimitbEditFieldLabel.Text = 'Upper limit, b';
            app.UpperlimitbEditField = uieditfield(app.TRAPEZOIDALRULETab, 'numeric');
            app.nEditField_3Label = uilabel(app.TRAPEZOIDALRULETab);
            app.nEditField_3Label.Text = 'n';
            app.nEditField_3 = uieditfield(app.TRAPEZOIDALRULETab, 'numeric');
            app.ResultsTextAreaLabel = uilabel(app.TRAPEZOIDALRULETab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.TRAPEZOIDALRULETab);
            app.SIMPSONRULETab = uitab(app.TabGroup);
            app.SIMPSONRULETab.Title = 'SIMPSON RULE';
            app.UIAxes_3 = uiaxes(app.SIMPSONRULETab);
            title(app.UIAxes_3, 'SIMPSON RULE')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.ComputeButtonSimpson = uibutton(app.SIMPSONRULETab, 'push');
            app.ComputeButtonSimpson.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonSimpsonPushed, true);
            app.ComputeButtonSimpson.Text = 'Compute';
            app.expx23x3Label_2 = uilabel(app.SIMPSONRULETab);
            app.expx23x3Label_2.Text = 'exp: x.^2 + 3*x -3';
            app.TheendoftheintervalLabel_2 = uilabel(app.SIMPSONRULETab);
            app.TheendoftheintervalLabel_2.Text = 'The end of the interval';
            app.ThestartoftheintervalLabel_2 = uilabel(app.SIMPSONRULETab);
            app.ThestartoftheintervalLabel_2.Text = 'The start of the interval';
            app.NumberofsubintervalsBasicn2Compositen2nLabel = uilabel(app.SIMPSONRULETab);
            app.NumberofsubintervalsBasicn2Compositen2nLabel.Text = {'Number of subintervals:'; 'Basic: n = 2'; 'Composite: n > 2n'};
            app.fxEditField_2Label = uilabel(app.SIMPSONRULETab);
            app.fxEditField_2Label.Text = 'f(x)';
            app.fxEditField_2 = uieditfield(app.SIMPSONRULETab, 'text');
            app.nEditField_4Label = uilabel(app.SIMPSONRULETab);
            app.nEditField_4Label.Text = 'n';
            app.nEditField_4 = uieditfield(app.SIMPSONRULETab, 'numeric');
            app.ResultsTextArea_2Label = uilabel(app.SIMPSONRULETab);
            app.ResultsTextArea_2Label.Text = 'Results';
            app.ResultsTextArea_2 = uitextarea(app.SIMPSONRULETab);
            app.LowerlimitaEditField_2Label = uilabel(app.SIMPSONRULETab);
            app.LowerlimitaEditField_2Label.Text = 'Lower limit, a';
            app.LowerlimitaEditField_2 = uieditfield(app.SIMPSONRULETab, 'numeric');
            app.UpperlimitbEditField_2Label = uilabel(app.SIMPSONRULETab);
            app.UpperlimitbEditField_2Label.Text = 'Upper limit, b';
            app.UpperlimitbEditField_2 = uieditfield(app.SIMPSONRULETab, 'numeric');
            app.MIDPOINTRULETab = uitab(app.TabGroup);
            app.MIDPOINTRULETab.Title = 'MIDPOINT RULE';
            app.UIAxes_4 = uiaxes(app.MIDPOINTRULETab);
            title(app.UIAxes_4, 'MIDPOINT RULE')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.ComputeButtonMidpoint = uibutton(app.MIDPOINTRULETab, 'push');
            app.ComputeButtonMidpoint.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonMidpointPushed, true);
            app.ComputeButtonMidpoint.Text = 'Compute';
            app.exp4x23x3Label = uilabel(app.MIDPOINTRULETab);
            app.exp4x23x3Label.Text = 'exp: 4-x.^2 + 3*x -3';
            app.TheendoftheintervalLabel_3 = uilabel(app.MIDPOINTRULETab);
            app.TheendoftheintervalLabel_3.Text = 'The end of the interval';
            app.ThestartoftheintervalLabel_3 = uilabel(app.MIDPOINTRULETab);
            app.ThestartoftheintervalLabel_3.Text = 'The start of the interval';
            app.NumberofsubintervalsBasicn1Compositen1Label_2 = uilabel(app.MIDPOINTRULETab);
            app.NumberofsubintervalsBasicn1Compositen1Label_2.Text = {'Number of subintervals:'; 'Basic: n = 1'; 'Composite: n > 1'};
            app.fxEditField_3Label = uilabel(app.MIDPOINTRULETab);
            app.fxEditField_3Label.Text = 'f(x)';
            app.fxEditField_3 = uieditfield(app.MIDPOINTRULETab, 'text');
            app.nEditField_5Label = uilabel(app.MIDPOINTRULETab);
            app.nEditField_5Label.Text = 'n';
            app.nEditField_5 = uieditfield(app.MIDPOINTRULETab, 'numeric');
            app.ResultsTextArea_3Label = uilabel(app.MIDPOINTRULETab);
            app.ResultsTextArea_3Label.Text = 'Results';
            app.ResultsTextArea_3 = uitextarea(app.MIDPOINTRULETab);
            app.LowerlimitaEditField_3Label = uilabel(app.MIDPOINTRULETab);
            app.LowerlimitaEditField_3Label.Text = 'Lower limit, a';
            app.LowerlimitaEditField_3 = uieditfield(app.MIDPOINTRULETab, 'numeric');
            app.UpperlimitbEditField_3Label = uilabel(app.MIDPOINTRULETab);
            app.UpperlimitbEditField_3Label.Text = 'Upper limit, b';
            app.UpperlimitbEditField_3 = uieditfield(app.MIDPOINTRULETab, 'numeric');
            app.NEWTONRAPHSONMETHODTab = uitab(app.TabGroup);
            app.NEWTONRAPHSONMETHODTab.Title = 'NEWTON RAPHSON METHOD';
            app.UIAxes2 = uiaxes(app.NEWTONRAPHSONMETHODTab);
            title(app.UIAxes2, 'NEWTON-RAPHSON METHOD')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ComputeRoot = uibutton(app.NEWTONRAPHSONMETHODTab, 'push');
            app.ComputeRoot.ButtonPushedFcn = createCallbackFcn(app, @ComputeRootButtonPushed2, true);
            app.ComputeRoot.Text = 'Compute';
            app.ResultTable = uitable(app.NEWTONRAPHSONMETHODTab);
            app.ResultTable.ColumnName = {'Iteration'; 'x_n'; 'f(x_n)'; 'f''(x_n)'; 'x_{n+1}'; 'x_{n+1} - x_n'};
            app.ResultTable.RowName = {};
            app.Expx33x1Label = uilabel(app.NEWTONRAPHSONMETHODTab);
            app.Expx33x1Label.Text = 'Exp: x.^3 +3*x +1';
            app.LeveloferrorthatisacceptableLabel = uilabel(app.NEWTONRAPHSONMETHODTab);
            app.LeveloferrorthatisacceptableLabel.Text = 'Level of error that is acceptable';
            app.MaximumiterationcanbedoneLabel = uilabel(app.NEWTONRAPHSONMETHODTab);
            app.MaximumiterationcanbedoneLabel.Text = 'Maximum iteration can be done';
            app.InitialguessaLabel = uilabel(app.NEWTONRAPHSONMETHODTab);
            app.InitialguessaLabel.Text = 'Initial guess (x_n) ';
            app.InitialguessEditField = uieditfield(app.NEWTONRAPHSONMETHODTab, 'numeric');
            app.ErrortoleranceEditFieldLabel = uilabel(app.NEWTONRAPHSONMETHODTab);
            app.ErrortoleranceEditFieldLabel.Text = 'Error tolerance ';
            app.ErrortoleranceEditField = uieditfield(app.NEWTONRAPHSONMETHODTab, 'numeric');
            app.FunctionfxLabel = uilabel(app.NEWTONRAPHSONMETHODTab);
            app.FunctionfxLabel.Text = 'Function f(x)';
            app.FunctionEditField_4 = uieditfield(app.NEWTONRAPHSONMETHODTab, 'text');
            app.MaxIterationsEditFieldLabel = uilabel(app.NEWTONRAPHSONMETHODTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.NEWTONRAPHSONMETHODTab, 'numeric');
            app.AnswerEditFieldLabel = uilabel(app.NEWTONRAPHSONMETHODTab);
            app.AnswerEditFieldLabel.Text = 'Answer';
            app.AnswerEditField = uieditfield(app.NEWTONRAPHSONMETHODTab, 'text');
            app.AnEducationalToolForNumericalAnalysisLabel = uilabel(app.UIFigure);
            app.AnEducationalToolForNumericalAnalysisLabel.Text = 'An Educational Tool For Numerical Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NumericalAssignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end