classdef divide < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InterpolationTab               matlab.ui.container.Tab
        only5andbelowLabel             matlab.ui.control.Label
        EvaluateAtEditField            matlab.ui.control.NumericEditField
        EvaluateatxLabel_2             matlab.ui.control.Label
        ResultTextArea                 matlab.ui.control.TextArea
        ResultTextAreaLabel            matlab.ui.control.Label
        EvaluateatxLabel               matlab.ui.control.Label
        MethodDropDownLabel            matlab.ui.control.Label
        ComputeButton                  matlab.ui.control.Button
        InputTable                     matlab.ui.control.Table
        XvalueEditField                matlab.ui.control.NumericEditField
        MethodDropDown                 matlab.ui.control.DropDown
        SplineTab                      matlab.ui.container.Tab
        Valuemustbein4andaboveLabel_2  matlab.ui.control.Label
        Valuemustbein4andaboveLabel    matlab.ui.control.Label
        SplineEquationsTextArea        matlab.ui.control.TextArea
        SplineEquationsTextAreaLabel   matlab.ui.control.Label
        MethodDropDown_2               matlab.ui.control.DropDown
        MethodDropDown_2Label          matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        EnteryEditField                matlab.ui.control.EditField
        EnteryEditFieldLabel           matlab.ui.control.Label
        EnterxEditField                matlab.ui.control.EditField
        EnterxEditFieldLabel           matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        RootFindingTab                 matlab.ui.container.Tab
        FunctionEditField              matlab.ui.control.EditField
        FunctionfxLabel                matlab.ui.control.Label
        MethodsDropDown                matlab.ui.control.DropDown
        MethodsDropDownLabel           matlab.ui.control.Label
        ErrorEditField                 matlab.ui.control.NumericEditField
        ErrorEditFieldLabel            matlab.ui.control.Label
        UpperboundEditField            matlab.ui.control.NumericEditField
        UpperboundbEditFieldLabel      matlab.ui.control.Label
        LowerboundEditField            matlab.ui.control.NumericEditField
        LowerboundaEditFieldLabel      matlab.ui.control.Label
        RootResultLabel                matlab.ui.control.Label
        ResultTable                    matlab.ui.control.Table
        ComputeRoot                    matlab.ui.control.Button
        IntegrationTab                 matlab.ui.container.Tab
        ResultsTextArea                matlab.ui.control.TextArea
        ResultsTextAreaLabel           matlab.ui.control.Label
        MethodDropDown_3               matlab.ui.control.DropDown
        MethodDropDown_3Label          matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        hEditField                     matlab.ui.control.NumericEditField
        hEditFieldLabel                matlab.ui.control.Label
        UpperLimitbEditField           matlab.ui.control.NumericEditField
        UpperLimitbEditFieldLabel      matlab.ui.control.Label
        LowerLimitaEditField           matlab.ui.control.NumericEditField
        LowerLimitaEditFieldLabel      matlab.ui.control.Label
        fxEditField                    matlab.ui.control.EditField
        fxEditFieldLabel               matlab.ui.control.Label
        ComputeIntegration             matlab.ui.control.Button
        nisalways2Label                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XvalueEditField
        function XvalueEditFieldValueChanged(app, event)
            n = app.XvalueEditField.Value;

            % Initialize table data with 7 columns:
            % i, x, f(x), 1st DD, 2nd DD, 3rd DD, 4th DD
            data = cell(n, 7);
            for i = 1:n
                data{i,1} = i-1; % index starting from 0
                data{i,4} = NaN;
                data{i,5} = NaN;
                data{i,6} = NaN;
                data{i,7} = NaN;
            end
            
            app.InputTable.Data = data;
            app.InputTable.ColumnName = {'i', 'x', 'f(x)', '1st DD', '2nd DD', '3rd DD', '4th DD'};
            app.InputTable.ColumnEditable = [false true true false false false false];
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            rawData = app.InputTable.Data;
            n = size(rawData, 1);
        
            % Initialize arrays for x and f(x)
            x = zeros(n,1);
            fx = zeros(n,1);
        
            % Validate and convert inputs from table columns 2 (x) and 3 (f(x))
            for i = 1:n
                xi = rawData{i, 2};
                fxi = rawData{i, 3};
        
                if ischar(xi) || isstring(xi)
                    xi = str2double(xi);
                end
                if ischar(fxi) || isstring(fxi)
                    fxi = str2double(fxi);
                end
        
                if isnumeric(xi) && isnumeric(fxi) && ~isempty(xi) && ~isempty(fxi) && ~isnan(xi) && ~isnan(fxi)
                    x(i) = xi;
                    fx(i) = fxi;
                else
                    uialert(app.UIFigure, sprintf('Invalid or missing value at row %d.', i), 'Error');
                    return;
                end
            end
        
            if length(x) < 2
                uialert(app.UIFigure, 'Please enter at least 2 numeric x and f(x) pairs.', 'Error');
                return;
            end
        
            % Sort x and fx in ascending order of x
            [x, idx] = sort(x);
            fx = fx(idx);
        
            method = app.MethodDropDown.Value;
            syms X s;
        
            % Initialize rawData with proper size and fill first 3 columns
            rawData = cell(n, 7);
            for i = 1:n
                rawData{i,1} = i-1; % iteration starting from 0
                rawData{i,2} = x(i);
                rawData{i,3} = fx(i);
                rawData{i,4} = NaN; % 1st DD
                rawData{i,5} = NaN; % 2nd DD
                rawData{i,6} = NaN; % 3rd DD
                rawData{i,7} = NaN; % 4th DD
            end
        
            evalX = app.EvaluateAtEditField.Value;
        
            switch method
                case 'Newton Divided'
                    DD = zeros(n, n);
                    DD(:,1) = fx(:);
        
                    for j = 2:n
                        for i = 1:n-j+1
                            DD(i,j) = (DD(i+1,j-1) - DD(i,j-1)) / (x(i+j-1) - x(i));
                        end
                    end
        
                    % Fill difference columns up to 4th difference (if available)
                    maxDiffCols = min(n-1, 4);
                    for col = 1:maxDiffCols
                        for row = 1:(n-col)
                            rawData{row, col+3} = DD(row, col+1);
                        end
                    end
        
                    % Construct Newton Divided Difference polynomial
                    poly = DD(1,1);
                    term = 1;
                    for i = 1:n-1
                        term = term * (X - x(i));
                        poly = poly + DD(1,i+1) * term;
                    end
                    poly = simplify(poly);
        
                case 'Newton Forward'
                    h = x(2) - x(1);
                    if any(abs(diff(x) - h) > 1e-6)
                        uialert(app.UIFigure, 'x values must be equally spaced.', 'Error');
                        return;
                    end
                    F = zeros(n);
                    F(:,1) = fx(:);
                    for j = 2:n
                        for i = 1:n-j+1
                            F(i,j) = F(i+1,j-1) - F(i,j-1);
                        end
                    end
        
                    maxDiffCols = min(n-1, 4);
                    for col = 1:maxDiffCols
                        for row = 1:(n-col)
                            rawData{row, col+3} = F(row, col+1);
                        end
                    end
        
                    s = (X - x(1))/h;
                    poly = F(1,1);
                    for k = 1:n-1
                        coeff = prod(s - (0:k-1)) / factorial(k);
                        poly = poly + coeff * F(1,k+1);
                    end
                    poly = simplify(poly);
        
                case 'Newton Backward'
                    h = x(2) - x(1);
                    if any(abs(diff(x) - h) > 1e-6)
                        uialert(app.UIFigure, 'x values must be equally spaced.', 'Error');
                        return;
                    end
                    B = zeros(n);
                    B(:,1) = fx(:);
                    for j = 2:n
                        for i = j:n
                            B(i,j) = B(i,j-1) - B(i-1,j-1);
                        end
                    end
        
                    maxDiffCols = min(n-1, 4);
                    for col = 2:maxDiffCols+1 % columns 2 to maxDiffCols+1 represent differences
                        for row = col:n
                            rawData{row, col+2} = B(row, col);  
                        end
                    end

                    s = (X - x(end))/h;
                    poly = B(n,1);
                    for k = 1:n-1
                        coeff = prod(s + (0:k-1)) / factorial(k);
                        poly = poly + coeff * B(n,k+1);
                    end
                    poly = simplify(poly);
        
                otherwise
                    uialert(app.UIFigure, 'Method not implemented.', 'Error');
                    return;
            end
        
            % Update table properties and data
            app.InputTable.Data = rawData;
            app.InputTable.ColumnName = {'i', 'x', 'f(x)', '1st DD', '2nd DD', '3rd DD', '4th DD'};
            app.InputTable.ColumnEditable = [false true true false false false false];
        
            % Evaluate polynomial at evalX
            polyFunc = matlabFunction(poly);
            val = polyFunc(evalX);
        
            % Show result text
            app.ResultTextArea.Value = {
                [method, ' Difference Polynomial (Degree ', num2str(n-1), '):'];
                char(poly);
                ['Polynomial evaluated at x = ', num2str(evalX), ' is: ', num2str(val)]
            };
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            xStr = strrep(app.EnterxEditField.Value, ',',' ');
            yStr = strrep(app.EnteryEditField.Value, ',', ' ');
            x = str2double(strsplit(xStr));
            y = str2double(strsplit(yStr));

            if any(isnan(x)) || any(isnan(y))
                uialert(app.UIFigure,'Invalid number in input.','Input Error');
                return;
            end
            if length(x) ~= length(y)
                uialert(app.UIFigure, 'X and Y must have the same number of elements.', 'Input Error');
                return;
            end
             if length(unique(x)) ~= length(x)
                uialert(app.UIFigure, 'At least two points are required.', 'Input Error');
                return;
             end
              [x, idx] = sort(x);
              y = y(idx);
              method = app.MethodDropDown_2.Value;
              xx = linspace(min(x), max(x), 200);
              cla(app.UIAxes);
              app.SplineEquationsTextArea.Value = "";
              
              switch method
                case 'Linear'
                    yy = interp1(x, y, xx, 'linear');
                    curveColor = 'g';
                    eqnText = "Linear Spline Equations:" + newline;
                    for i = 1:length(x)-1
                        m = (y(i+1) - y(i)) / (x(i+1) - x(i));
                        c = y(i) - m*x(i);  % y = mx + c
                        eqn = sprintf("S_%d(x) = %.4fx + %.4f \tfor x in [%.2f, %.2f]", ...
                              i, m, c, x(i), x(i+1));
                        eqnText = eqnText + eqn + newline;
                    end
                    app.SplineEquationsTextArea.Value = eqnText;
                case 'Cubic'
                    yy = spline(x, y, xx);
                    curveColor = 'b';
                    pp = spline(x, y);
                    coeffs = pp.coefs;
                    breaks = pp.breaks;
                    nSeg = length(breaks)-1;

                    if size(coeffs, 1) ~= nSeg
                        uialert(app.UIFigure,'Mismatch in coefficient and breakpoint data.','Internal Error');
                        return;
                    end
                  
                    eqnText = "Cubic Spline Equations:" + newline;
                    
                    for i = 1:nSeg
                        d = coeffs(i, 1);
                        c = coeffs(i, 2);
                        b = coeffs(i, 3);
                        a = coeffs(i, 4);
                        xi = breaks(i);
                        xi1 = breaks(i+1);
                        eqn = sprintf("S_%d(x) = %.4f + %.4f(x - %.2f) + %.4f(x - %.2f)^2 + %.4f(x - %.2f)^3  for x in [%.2f, %.2f]", ...
                             i, a, b, xi, c, xi, d, xi, xi, xi1);
                        eqnText = eqnText + eqn + newline;
                    end
                    disp(eqnText)
                    app.SplineEquationsTextArea.Value = eqnText;
                    
              end
              plot(app.UIAxes, x, y, 'ro', 'MarkerSize', 8, 'DisplayName', 'Data Points');
              hold(app.UIAxes, 'on');
              plot(app.UIAxes, xx, yy, [curveColor '-'], 'LineWidth', 2, ...
              'DisplayName', [method ' Spline']);
              legend(app.UIAxes, 'show');
              title(app.UIAxes, [method ' Spline Interpolation']);
              xlabel(app.UIAxes, 'x');
              ylabel(app.UIAxes, 'y');
              grid(app.UIAxes, 'on');
              hold(app.UIAxes, 'off');
        end

        % Button pushed function: ComputeRoot
        function ComputeRootButtonPushed(app, event)
            a = app.LowerboundEditField.Value;
            b = app.UpperboundEditField.Value;
            tol = app.ErrorEditField.Value;
            funcStr = app.FunctionEditField.Value;
            method = app.MethodsDropDown.Value;

            % Convert string to function handle
            try
                f = str2func(['@(x)', funcStr]);
                f(1); % test validity
            catch
                uialert(app.UIFigure, 'Invalid function input. Please enter a valid function of x.', 'Error');
                return;
            end

            % Apply selected method
            try
                if strcmp(method, 'Bisection Method')
                    results = app.bisection_method(f, a, b, tol);
                    app.ResultTable.ColumnName = {'Iteration', 'a', 'b', 'f(a)', 'f(b)', 'c', 'f(c)'};
                    app.RootResultLabel.Text = 'Bisection Method Results';

                elseif strcmp(method, 'Newton-Raphson Method')
                    results = app.newton_method(f, a, tol);  % initial guess = a
                    app.ResultTable.ColumnName = {'Iteration', 'a', 'f(a)', 'f''(a)', 'b', '|b-a|'};
                    app.RootResultLabel.Text = 'Newton-Raphson Method Results';

                elseif strcmp(method, 'Secant Method')
                    results = app.secant_method(f, a, b, tol);
                    app.ResultTable.ColumnName = {'Iteration', 'x0', 'x1', 'f(x0)', 'f(x1)', 'x2', 'Error'};
                    app.RootResultLabel.Text = 'Secant Method Results';

                else
                    uialert(app.UIFigure, 'Selected method is not implemented yet.', 'Notice');
                    return;
                end

                % Display result in table
                app.ResultTable.Data = struct2table(results);

            catch ME
                uialert(app.UIFigure, ME.message, 'Calculation Error');
            end
        end

        % --- Bisection Method ---
        function data = bisection_method(~, f, a, b, tol)
            i = 0;
            data = struct('Iteration', {}, 'a', {}, 'b', {}, 'f_a', {}, 'f_b', {}, 'c', {}, 'f_c', {});
            if f(a) * f(b) > 0
                error('f(a) and f(b) must have opposite signs.');
            end

            while (b - a)/2 > tol
                c = (a + b)/2;
                i = i + 1;
                data(i).Iteration = i;
                data(i).a = a;
                data(i).b = b;
                data(i).f_a = f(a);
                data(i).f_b = f(b);
                data(i).c = c;
                data(i).f_c = f(c);

                if f(c) == 0
                    break;
                elseif sign(f(c)) == sign(f(a))
                    a = c;
                else
                    b = c;
                end
            end
        end

        % --- Newton-Raphson Method ---
        function data = newton_method(~, f, x0, tol)
            max_iter = 100;
            h = 1e-6;
            data = struct('Iteration', {}, 'a', {}, 'f_a', {}, 'f_prime_a', {}, 'b', {}, 'b_minus_a', {});
            for i = 1:max_iter
                f_val = f(x0);
                f_prime = (f(x0 + h) - f(x0 - h)) / (2 * h);
                if abs(f_prime) < 1e-12
                    error('Derivative too small. Division by zero risk.');
                end
                x1 = x0 - f_val / f_prime;

                data(i).Iteration = i;
                data(i).a = x0;
                data(i).f_a = f_val;
                data(i).f_prime_a = f_prime;
                data(i).b = x1;
                data(i).b_minus_a = x1 - x0;

                if abs(x1 - x0) < tol
                    break;
                end
                x0 = x1;
            end
        end

        % --- Secant Method ---
        function data = secant_method(~, f, x0, x1, tol)
            max_iter = 100;
            data = struct('Iteration', {}, 'x0', {}, 'x1', {}, 'f_x0', {}, 'f_x1', {}, 'x2', {}, 'Error', {});
            for i = 1:max_iter
                f_x0 = f(x0);
                f_x1 = f(x1);
                if (f_x1 - f_x0) == 0
                    error('Division by zero in Secant method.');
                end
                x2 = x1 - f_x1 * (x1 - x0) / (f_x1 - f_x0);
                err = abs(x2 - x1);

                data(i).Iteration = i;
                data(i).x0 = x0;
                data(i).x1 = x1;
                data(i).f_x0 = f_x0;
                data(i).f_x1 = f_x1;
                data(i).x2 = x2;
                data(i).Error = err;

                if err < tol
                    break;
                end
                x0 = x1;
                x1 = x2;
            end
        end

        % Button pushed function: ComputeIntegration
        function ComputeIntegrationButtonPushed(app, event)
            % Get user input
            funcStr = app.fxEditField.Value;
            a = app.LowerLimitaEditField.Value;
            b = app.UpperLimitbEditField.Value;
            n = app.nEditField.Value;
            method = app.MethodDropDown_3.Value; 
        
            % Validate n = 2
            if n ~= 2
                app.ResultsTextArea.Value = {'Error: This app supports only n = 2.'};
                return;
            end
        
            % Try to convert function string to function handle
            try
                f = str2func(['@(x)', funcStr]);
                % Test it with midpoint to catch errors
                test = f((a + b) / 2);
            catch
                app.ResultsTextArea.Value = {'Error: Invalid function format.'};
                return;
            end
        
            % Compute numerical integration based on method
            try
                h = (b - a) / 2;
        
                switch method
                    case 'Simpson'
                        x0 = a;
                        x1 = (a + b) / 2;
                        x2 = b;
                        y0 = f(x0);
                        y1 = f(x1);
                        y2 = f(x2);
                        integral_approx = (h / 3) * (y0 + 4*y1 + y2);
                        methodStr = 'Simpson''s Rule';
        
                    case 'Trapezoidal'
                        x0 = a;
                        x1 = a + h;
                        x2 = b;
                        y0 = f(x0);
                        y1 = f(x1);
                        y2 = f(x2);
                        integral_approx = (h) * (f(a) + f(b));
                        methodStr = 'Trapezoidal Rule';
        
                    case 'Midpoint'
                        x_mid = (a + b) / 2;
                        y_mid = f(x_mid);
                        integral_approx = (b - a) * y_mid;
                        methodStr = 'Midpoint Rule';
        
                    otherwise
                        app.ResultsTextArea.Value = {'Error: Unknown method selected.'};
                        return;
                end
            catch
                app.ResultsTextArea.Value = {'Error computing numerical integral.'};
                return;
            end
        
            % Compute exact integral using symbolic math
            try
                syms x
                f_sym = str2sym(funcStr);
                int_exact = double(int(f_sym, x, a, b));
            catch
                int_exact = NaN;
            end
        
            % Display results
            app.ResultsTextArea.Value = {
                ['Method: ' methodStr]
                sprintf('Numerical Integral ≈ %.6f', integral_approx)
                sprintf('Exact Integral = %.6f', int_exact)
            };

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.MethodDropDown = uidropdown(app.InterpolationTab);
            app.MethodDropDown.Items = {'Newton Divided', 'Newton Forward', 'Newton Backward'};
            app.MethodDropDown.Value = 'Newton Divided';
            app.XvalueEditField = uieditfield(app.InterpolationTab, 'numeric');
            app.XvalueEditField.ValueChangedFcn = createCallbackFcn(app, @XvalueEditFieldValueChanged, true);
            app.InputTable = uitable(app.InterpolationTab);
            app.InputTable.ColumnName = {'i'; 'x'; 'f(x)'; '1st DD'; '2nd DD'; '3rd DD'; '4th DD'; '5th DD'};
            app.InputTable.RowName = {};
            app.ComputeButton = uibutton(app.InterpolationTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.MethodDropDownLabel = uilabel(app.InterpolationTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.EvaluateatxLabel = uilabel(app.InterpolationTab);
            app.EvaluateatxLabel.Text = 'No. of iterations (include 0) =';
            app.ResultTextAreaLabel = uilabel(app.InterpolationTab);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.InterpolationTab);
            app.EvaluateatxLabel_2 = uilabel(app.InterpolationTab);
            app.EvaluateatxLabel_2.Text = 'Evaluate at x =';
            app.EvaluateAtEditField = uieditfield(app.InterpolationTab, 'numeric');
            app.only5andbelowLabel = uilabel(app.InterpolationTab);
            app.only5andbelowLabel.Text = '(only 5 and below)';
            app.SplineTab = uitab(app.TabGroup);
            app.SplineTab.Title = 'Spline';
            app.UIAxes = uiaxes(app.SplineTab);
            title(app.UIAxes, 'Spline Interpolation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EnterxEditFieldLabel = uilabel(app.SplineTab);
            app.EnterxEditFieldLabel.Text = 'Enter x';
            app.EnterxEditField = uieditfield(app.SplineTab, 'text');
            app.EnteryEditFieldLabel = uilabel(app.SplineTab);
            app.EnteryEditFieldLabel.Text = 'Enter y';
            app.EnteryEditField = uieditfield(app.SplineTab, 'text');
            app.PlotButton = uibutton(app.SplineTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MethodDropDown_2Label = uilabel(app.SplineTab);
            app.MethodDropDown_2Label.Text = 'Method';
            app.MethodDropDown_2 = uidropdown(app.SplineTab);
            app.MethodDropDown_2.Items = {'Linear', 'Cubic'};
            app.MethodDropDown_2.Value = 'Linear';
            app.SplineEquationsTextAreaLabel = uilabel(app.SplineTab);
            app.SplineEquationsTextAreaLabel.Text = 'Spline Equations:';
            app.SplineEquationsTextArea = uitextarea(app.SplineTab);
            app.Valuemustbein4andaboveLabel = uilabel(app.SplineTab);
            app.Valuemustbein4andaboveLabel.Text = 'Value must be in 4 and above';
            app.Valuemustbein4andaboveLabel_2 = uilabel(app.SplineTab);
            app.Valuemustbein4andaboveLabel_2.Text = 'Value must be in 4 and above';
            app.RootFindingTab = uitab(app.TabGroup);
            app.RootFindingTab.Title = 'RootFinding';
            app.ComputeRoot = uibutton(app.RootFindingTab, 'push');
            app.ComputeRoot.ButtonPushedFcn = createCallbackFcn(app, @ComputeRootButtonPushed, true);
            app.ComputeRoot.Text = 'Compute';
            app.ResultTable = uitable(app.RootFindingTab);
            app.ResultTable.ColumnName = {'Iter'; 'a'; 'b'; 'f(a)'; 'f(b)'; 'c'; 'f(c)'};
            app.ResultTable.RowName = {};
            app.RootResultLabel = uilabel(app.RootFindingTab);
            app.RootResultLabel.Text = 'Root = ';
            app.LowerboundaEditFieldLabel = uilabel(app.RootFindingTab);
            app.LowerboundaEditFieldLabel.Text = 'Lower bound (a) = ';
            app.LowerboundEditField = uieditfield(app.RootFindingTab, 'numeric');
            app.UpperboundbEditFieldLabel = uilabel(app.RootFindingTab);
            app.UpperboundbEditFieldLabel.Text = 'Upper bound (b) =';
            app.UpperboundEditField = uieditfield(app.RootFindingTab, 'numeric');
            app.ErrorEditFieldLabel = uilabel(app.RootFindingTab);
            app.ErrorEditFieldLabel.Text = 'Error = ';
            app.ErrorEditField = uieditfield(app.RootFindingTab, 'numeric');
            app.MethodsDropDownLabel = uilabel(app.RootFindingTab);
            app.MethodsDropDownLabel.Text = 'Methods';
            app.MethodsDropDown = uidropdown(app.RootFindingTab);
            app.MethodsDropDown.Items = {'Bisection Method', 'Newton-Raphson Method', 'Secant Method'};
            app.MethodsDropDown.Value = 'Bisection Method';
            app.FunctionfxLabel = uilabel(app.RootFindingTab);
            app.FunctionfxLabel.Text = 'Function f(x)';
            app.FunctionEditField = uieditfield(app.RootFindingTab, 'text');
            app.IntegrationTab = uitab(app.TabGroup);
            app.IntegrationTab.Title = 'Integration';
            app.nisalways2Label = uilabel(app.IntegrationTab);
            app.nisalways2Label.Text = 'n is always 2';
            app.ComputeIntegration = uibutton(app.IntegrationTab, 'push');
            app.ComputeIntegration.ButtonPushedFcn = createCallbackFcn(app, @ComputeIntegrationButtonPushed, true);
            app.ComputeIntegration.Text = 'Compute';
            app.fxEditFieldLabel = uilabel(app.IntegrationTab);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.IntegrationTab, 'text');
            app.LowerLimitaEditFieldLabel = uilabel(app.IntegrationTab);
            app.LowerLimitaEditFieldLabel.Text = 'Lower  Limit , a';
            app.LowerLimitaEditField = uieditfield(app.IntegrationTab, 'numeric');
            app.UpperLimitbEditFieldLabel = uilabel(app.IntegrationTab);
            app.UpperLimitbEditFieldLabel.Text = 'Upper Limit , b';
            app.UpperLimitbEditField = uieditfield(app.IntegrationTab, 'numeric');
            app.hEditFieldLabel = uilabel(app.IntegrationTab);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.IntegrationTab, 'numeric');
            app.nEditFieldLabel = uilabel(app.IntegrationTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.IntegrationTab, 'numeric');
            app.MethodDropDown_3Label = uilabel(app.IntegrationTab);
            app.MethodDropDown_3Label.Text = 'Method';
            app.MethodDropDown_3 = uidropdown(app.IntegrationTab);
            app.MethodDropDown_3.Items = {'Trapezoidal', 'Simpson', 'Midpoint'};
            app.MethodDropDown_3.Value = 'Trapezoidal';
            app.ResultsTextAreaLabel = uilabel(app.IntegrationTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.IntegrationTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = divide

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end